classdef depthmap_viewer_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DepthmapViewerGUI               matlab.ui.Figure
        load_btn                        matlab.ui.control.Button
        DepthmapFilesLabel              matlab.ui.control.Label
        data_lb                         matlab.ui.control.ListBox
        binary_cb                       matlab.ui.control.CheckBox
        Panel                           matlab.ui.container.Panel
        MinDepthmapValueEditFieldLabel  matlab.ui.control.Label
        min_dm_tb                       matlab.ui.control.NumericEditField
        MaxDepthmapValueEditFieldLabel  matlab.ui.control.Label
        max_dm_tb                       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        data_path % Description
        dm_img % Description
        f % Description
    end
    
    methods (Access = private)
        
    %--------------------------------------------------------------------------

        function uifigurepop(app)
            drawnow;
            app.DepthmapViewerGUI.Visible = 'off';
            app.DepthmapViewerGUI.Visible = 'on';
        end  
        
        function update_dm_plot(app)
            
            min_dm_value = app.min_dm_tb.Value;
            max_dm_value = app.max_dm_tb.Value;
        
            [dm_h, dm_w] = size(app.dm_img);
            ratio = dm_h/dm_w;
            
%             surf(app.depth_ax, (app.dm_img));
%             shading(app.depth_ax, "interp");
%             colormap(app.depth_ax, jet(max_dm_value-min_dm_value));
%             axis(app.depth_ax, "tight");
%             view(app.depth_ax, 0, 90);
            
            
%             f = figure(100);
%             set(gcf,'position',([400,100,1000,floor(1000*ratio)]),'color','w')
            %surf(app.dm_img);
            %shading("interp");
            app.f;
            ax = gca;
            
            image(ax, (app.dm_img));
            colormap(ax, jet(max_dm_value-min_dm_value));
            axis(ax, 'tight', 'off');
            ax.Position = [0.025 0.045 0.95 0.91];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            full_path = mfilename('fullpath');
            [app.data_path,  ~, ~] = fileparts(full_path);
            
            app.f = figure(100);
            set(app.f,'position',([400,100,1000,floor(1000*.5)]),'color','w', 'ToolBar', 'none');
            %axis(app.depth_ax, 'off');
            %dcm_obj = datacursormode(app.DepthmapViewerGUI);
            %dcm_obj.Enable = 'on';

        end

        % Button pushed function: load_btn
        function load_btnButtonPushed(app, event)
            file_filter = {'*.txt','Text Files';'*.*','All Files' };
            
            [input_file, input_path] = uigetfile(file_filter, 'Select Data Input File', app.data_path);
            if(input_path == 0)
                return;
            end
            
            uifigurepop(app);
                       
            input_data = parse_input_parameters(fullfile(input_path, input_file));
            app.data_path = input_data{1}{1};
            input_data(1) = [];
            
            tmp = cell(length(input_data),1);

            % get the depth map files
            for idx=1:length(input_data)    
                tmp_file = strsplit(input_data{idx}{3},{'_','.'});
                %tmp{idx,1} = strcat(tmp_file{1},'_',tmp_file{2},'_',tmp_file{3},'_',tmp_file{4},'.bin');
                tmp{idx,1} = input_data{idx}{3};
            end
            dm_files = unique(tmp);
            
            app.data_lb.Items = dm_files;
            app.data_lb.ItemsData = [1:numel(dm_files)];
            
        end

        % Value changed function: data_lb
        function data_lbValueChanged(app, event)
            index = app.data_lb.Value;
            
            filename = app.data_lb.Items{index};
            
            if(app.binary_cb.Value == true)
                % open the file and read as a little-endian 
                file_id = fopen(fullfile(app.data_path, filename), 'r', 'l');
                
                % read the size
                sz = fread(file_id, 2, 'uint32');
                 
                % read in the data
                data = fread(file_id, sz(1)*sz(2), 'uint16');
                dm_img = double(reshape(data,[sz(1),sz(2)]));
            
                %data = single(data);
                fclose(file_id);            
            else
                dm_img = double(imread(fullfile(app.data_path, filename)));
            end
            
            app.dm_img = dm_img(10:end, 1:end-10, 1);
            
            update_dm_plot(app);
        end

        % Value changed function: max_dm_tb, min_dm_tb
        function dm_tbValueChanged(app, event)
            update_dm_plot(app);
        end

        % Close request function: DepthmapViewerGUI
        function DepthmapViewerGUICloseRequest(app, event)
            close(app.f);
            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DepthmapViewerGUI = uifigure('Visible', 'off');
            app.DepthmapViewerGUI.Name = 'UI Figure';
            app.DepthmapViewerGUI.CloseRequestFcn = createCallbackFcn(app, @DepthmapViewerGUICloseRequest, true);
            app.load_btn = uibutton(app.DepthmapViewerGUI, 'push');
            app.load_btn.ButtonPushedFcn = createCallbackFcn(app, @load_btnButtonPushed, true);
            app.load_btn.Text = 'Load Data';
            app.DepthmapFilesLabel = uilabel(app.DepthmapViewerGUI);
            app.DepthmapFilesLabel.Text = 'Depthmap Files';
            app.data_lb = uilistbox(app.DepthmapViewerGUI);
            app.data_lb.Items = {};
            app.data_lb.ValueChangedFcn = createCallbackFcn(app, @data_lbValueChanged, true);
            app.data_lb.Value = {};
            app.binary_cb = uicheckbox(app.DepthmapViewerGUI);
            app.binary_cb.Text = 'Binary Data';
            app.Panel = uipanel(app.DepthmapViewerGUI);
            app.Panel.Title = 'Panel';
            app.MinDepthmapValueEditFieldLabel = uilabel(app.Panel);
            app.MinDepthmapValueEditFieldLabel.Text = 'Min Depthmap Value';
            app.min_dm_tb = uieditfield(app.Panel, 'numeric');
            app.min_dm_tb.Limits = [0 Inf];
            app.min_dm_tb.ValueChangedFcn = createCallbackFcn(app, @dm_tbValueChanged, true);
            app.MaxDepthmapValueEditFieldLabel = uilabel(app.Panel);
            app.MaxDepthmapValueEditFieldLabel.Text = 'Max Depthmap Value';
            app.max_dm_tb = uieditfield(app.Panel, 'numeric');
            app.max_dm_tb.Limits = [0 Inf];
            app.max_dm_tb.ValueChangedFcn = createCallbackFcn(app, @dm_tbValueChanged, true);
            app.max_dm_tb.Value = 20;
            % Show the figure after all components are created
            app.DepthmapViewerGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = depthmap_viewer_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DepthmapViewerGUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DepthmapViewerGUI)
        end
    end
end