classdef analyze_lens_step < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LensAnalysisToolUIFigure  matlab.ui.Figure
        Lens_Plot                 matlab.ui.control.UIAxes
        LensStepListBoxLabel      matlab.ui.control.Label
        LensStepListBox           matlab.ui.control.ListBox
        LoadDataButton            matlab.ui.control.Button
        PlotDataButton            matlab.ui.control.Button
        SavePlotButton            matlab.ui.control.Button
    end


    properties (Access = private)
        data_path % Description
        pixel_radius % Description
        lens_step
        data
        
        plot_data % Description
        lens_plot_step
        
        range % Description
        save_path % Description
    end

    methods (Access = private)
    
    %--------------------------------------------------------------------------

        function uifigurepop(app)
            drawnow;
            app.LensAnalysisToolUIFigure.Visible = 'off';
            app.LensAnalysisToolUIFigure.Visible = 'on';
        end  
        
        function plot_lens_data(app)
            
            markers = {'o','*','s','d','^','v','p','h'};
            
            hold(app.Lens_Plot,'off');
            %min_range = floor(min(app.plot_data(:)));
            min_range = 0.3; %floor(min(app.range(:)));
            max_range = 3.5; %ceil(max(app.range(:)));
            x_step = 0.1;
            
            idx=1;
            nn_idx = ~isnan(app.plot_data(idx,:));
            %x = app.plot_data(idx,nn_idx);
            x = app.range(nn_idx);
            y = app.plot_data(1,nn_idx);
            lgd_str{idx,1} = num2str(app.lens_plot_step(idx));
            
            %set(app.Lens_Plot,'defaultAxesColorOrder',lines(7), 'defaultAxesLineStyleOrder','-|--|-.')
            app.Lens_Plot.LineStyleOrder = '-|--|-.';
            app.Lens_Plot.ColorOrder = lines(7);
            
            m = 1;
            plot(app.Lens_Plot, x, y,'Marker',markers{mod(m,numel(markers))+1}, 'MarkerSize',4, 'MarkerFaceColor','auto')            
            %plot(app.Lens_Plot, x, y, 'MarkerSize',4)            
            m = m + 1;
            hold(app.Lens_Plot,'on');
            for idx=2:size(app.plot_data)
                nn_idx = ~isnan(app.plot_data(idx,:));
                %x = app.plot_data(idx,nn_idx);
                x = app.range(nn_idx);
                y = app.plot_data(idx,nn_idx);
                lgd_str{idx,1} = num2str(app.lens_plot_step(idx));
                
                plot(app.Lens_Plot, x, y,'Marker',markers{mod(m,numel(markers))+1}, 'MarkerSize',4, 'MarkerFaceColor','auto')
                %plot(app.Lens_Plot, x, y, 'MarkerSize',4)
                m = m + 1;
                     
            end
            
            if(numel(app.lens_plot_step) == 2)
                x = app.range(nn_idx);
                y = abs(app.plot_data(1,nn_idx)-app.plot_data(2,nn_idx));
                lgd_str{end+1,1} = 'Pixel Radius Difference';
                plot(app.Lens_Plot, x, y,'Color','k', 'LineStyle','--', 'LineWidth',0.8, 'Marker','none', 'MarkerFaceColor','k', 'MarkerSize', 8)
            end
    
            % X-Axis
            xlim(app.Lens_Plot, [min_range, max_range]);
            xticks(app.Lens_Plot, [min_range:x_step:max_range]);
            %xticklabels(app.Lens_Plot,[min_bin:plot_step:max_bin]);
            xtickformat(app.Lens_Plot, '%1.1f');
            xtickangle(app.Lens_Plot, 90);
            xlabel(app.Lens_Plot, strcat('Range from Camera (m)'),'fontweight','bold','FontSize',14)
            
            % Y-Axis
            y_max = max(app.plot_data(:));
            ylabel(app.Lens_Plot, 'Pixel Blur Radius','fontweight','bold','FontSize',14)
            ylim(app.Lens_Plot, [0 (y_max+1)]);
            yticks(app.Lens_Plot, [0:1:(y_max+1)]);
            
            
            title(app.Lens_Plot, 'Quantized Blur Radius vs. Distance From Camera', 'fontweight','bold','FontSize',16);
            legend(app.Lens_Plot, lgd_str, 'location','northeast');
                        
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.save_path = 'D:\IUPUI\PhD\Images\Camera';
            app.Lens_Plot.PlotBoxAspectRatio = [1.3 .65 .5];
            app.Lens_Plot.Position = [5 41 750 432];
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            file_filter = {'*.txt','Text Files';'*.*','All Files' };
            
            app.data_path = 'D:/IUPUI/DfD/dfd_dnn_rw';
            [input_file, input_path] = uigetfile(file_filter, 'Select Data Input File', app.data_path);
            if(input_path == 0)
                return;
            end
            
            uifigurepop(app);
            
            app.lens_step = [];
            app.data = [];
            app.range = [];
            
            input_data = parse_input_parameters(fullfile(input_path, input_file));
            
            %app.pixel_radius = str2double({input_data{1}{2:end}});
            app.range = str2double({input_data{1}{2:end}});
           
            for idx=2:numel(input_data)
                 app.lens_step(idx-1) = str2double(input_data{idx}{1});
                 tmp{idx-1,1} = input_data{idx}{1};
                 app.data(idx-1,:) = str2double({input_data{idx}{2:end}});   
            end
            
            app.LensStepListBox.Items = tmp;
            app.LensStepListBox.ItemsData = [1:numel(app.lens_step)];
                   
        end

        % Button pushed function: PlotDataButton
        function PlotDataButtonPushed(app, event)
            
            % get the indecies of which items were selected or fill in default values
            if(~isempty(app.LensStepListBox.Value))
                %train_idx = setdiff(app.lb_train_data.ItemsData, app.lb_train_data.Value);
                lens_idx = app.LensStepListBox.Value;
            else
                lens_idx = app.LensStepListBox.ItemsData;
            end
            
            app.plot_data = app.data(lens_idx,:);
            app.lens_plot_step = app.lens_step(lens_idx);
            
            plot_lens_data(app);
             
        end

        % Button pushed function: SavePlotButton
        function SavePlotButtonPushed(app, event)
            
            % get the file
            file_filter = {'*.png','Image Files'; '*.fig','Figure Files'; '*.*','All Files' };
            [image_file, tmp_path] = uiputfile(file_filter, 'Enter save file', app.save_path);
         
            
            if(tmp_path == 0)
                return;
            end
            app.save_path = tmp_path;
            
            [~,~,ext] = fileparts(image_file);
            
            
            % get the indecies of which items were selected or fill in default values
            if(~isempty(app.LensStepListBox.Value))
                %train_idx = setdiff(app.lb_train_data.ItemsData, app.lb_train_data.Value);
                lens_idx = app.LensStepListBox.Value;
            else
                lens_idx = app.LensStepListBox.ItemsData;
            end
            
            markers = {'o','*','s','d','^','v','p','h'};
            
            app.plot_data = app.data(lens_idx,:);
            app.lens_plot_step = app.lens_step(lens_idx);
            
            fig=figure(100);
            set(fig,'position',([100,100,1200,600]),'color','w');
            set(gca,'ColorOrder',lines(7), 'LineStyleOrder','-|--|-.');
            
            hold on
            box on
            grid on
                      
            %min_range = floor(min(app.plot_data(:)));
            min_range = 0.3; %floor(min(app.range(:)));
            max_range = 3.5; %ceil(max(app.range(:)));
            x_step = 0.1;
            
            idx=1;
            nn_idx = ~isnan(app.plot_data(idx,:));
            %x = app.plot_data(idx,nn_idx);
            x = app.range(nn_idx);
            y = app.plot_data(idx, nn_idx);
            lgd_str{idx,1} = num2str(app.lens_plot_step(idx));
            
            m = 1;
            p{idx,1} = plot(fig.CurrentAxes, x, y,'Marker',markers{mod(m,numel(markers))+1}, 'MarkerSize',5, 'LineWidth',1);
            m = m + 1;
            
            hold(fig.CurrentAxes,'on');
            for idx=2:size(app.plot_data)
                nn_idx = ~isnan(app.plot_data(idx,:));
                %x = app.plot_data(idx,nn_idx);
                x = app.range(nn_idx);
                y = app.plot_data(idx, nn_idx);
                lgd_str{idx, 1} = num2str(app.lens_plot_step(idx));
                
                p{idx,1} = plot(fig.CurrentAxes, x, y,'Marker',markers{mod(m,numel(markers))+1}, 'MarkerSize',5, 'LineWidth',1);
                m = m + 1;
            end
        
            if(numel(app.lens_plot_step) == 2)
                x = app.range(nn_idx);
                y = abs(app.plot_data(1,nn_idx)-app.plot_data(2,nn_idx));
                lgd_str{end+1,1} = 'Pixel Radius Difference';
                plot(fig.CurrentAxes, x, y,'Color','k', 'LineStyle','--', 'LineWidth',0.8, 'Marker','none', 'MarkerFaceColor','k', 'MarkerSize', 8)
                p{1,1}.Color = 'b';
                p{2,1}.Color = 'r';            
            end
                        
            % X-Axis
            xlim(fig.CurrentAxes, [min_range, max_range]);
            xticks(fig.CurrentAxes, [min_range:x_step:max_range]);
            xtickformat(fig.CurrentAxes, '%1.1f');
            xtickangle(fig.CurrentAxes, 90);
            xlabel(fig.CurrentAxes, strcat('Distance from Camera (m)'),'fontweight','bold','FontSize',14)
            
            % Y-Axis
            y_max = max(app.plot_data(:));
            ylabel(fig.CurrentAxes, 'Pixel Blur Radius','fontweight','bold','FontSize',14)
            ylim(fig.CurrentAxes, [0 (y_max+1)]);
            yticks(fig.CurrentAxes, [0:1:(y_max+1)]);
            
            set(fig.CurrentAxes, 'fontweight','bold', 'FontSize',13);
            title(fig.CurrentAxes, 'Quantized Blur Radius vs. Distance From Camera', 'fontweight','bold','FontSize',16);
            legend(fig.CurrentAxes, lgd_str, 'location','southoutside', 'orientation','horizontal', 'fontweight','bold', 'FontSize',13);
            fig.CurrentAxes.Position = [0.06 0.17 0.92 0.77];
            
            if(strcmp(ext, '.png')==1)
                print(fig, '-dpng', fullfile(app.save_path, image_file));
                
            elseif(strcmp(ext, '.fig') == 1)
                savefig(fig, fullfile(app.save_path, image_file));
            end
            
            close(fig);
            
            uifigurepop(app);            
                    
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LensAnalysisToolUIFigure = uifigure;
            app.LensAnalysisToolUIFigure.Name = 'Lens Analysis Tool';
            app.Lens_Plot = uiaxes(app.LensAnalysisToolUIFigure);
            title(app.Lens_Plot, 'Quantized Blur Radius vs. Distance From Camera')
            xlabel(app.Lens_Plot, 'Range From Camera (m)')
            ylabel(app.Lens_Plot, 'Pixel Radius')
            app.LensStepListBoxLabel = uilabel(app.LensAnalysisToolUIFigure);
            app.LensStepListBoxLabel.Text = 'Lens Step';
            app.LensStepListBox = uilistbox(app.LensAnalysisToolUIFigure);
            app.LensStepListBox.Items = {};
            app.LensStepListBox.Multiselect = 'on';
            app.LensStepListBox.Value = {};
            app.LoadDataButton = uibutton(app.LensAnalysisToolUIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.PlotDataButton = uibutton(app.LensAnalysisToolUIFigure, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButtonPushed, true);
            app.PlotDataButton.Text = 'Plot Data';
            app.SavePlotButton = uibutton(app.LensAnalysisToolUIFigure, 'push');
            app.SavePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlotButtonPushed, true);
            app.SavePlotButton.Text = 'Save Plot';
        end
    end

    methods (Access = public)

        % Construct app
        function app = analyze_lens_step

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LensAnalysisToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LensAnalysisToolUIFigure)
        end
    end
end